classdef ReachabilityGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FirstLamp___________1          matlab.ui.control.Lamp
        First___________1              matlab.ui.control.Label
        URDFFileNameEditField          matlab.ui.control.EditField
        URDFFileNameLabel              matlab.ui.control.Label
        HomeJointPositionEditField     matlab.ui.control.EditField
        HomeJointPositionLabel         matlab.ui.control.Label
        DesiredJointPositionEditField  matlab.ui.control.EditField
        DesiredJointPositionLabel      matlab.ui.control.Label
        BrowseURDFButton               matlab.ui.control.Button
        ImportRobotButton              matlab.ui.control.Button
        ShowRobotButton                matlab.ui.control.Button
        SecondLamp___________2         matlab.ui.control.Lamp
        Second___________2             matlab.ui.control.Label
        EndEffector1DropDownLabel      matlab.ui.control.Label
        EndEffector1DropDown           matlab.ui.control.DropDown
        EndEffector2DropDownLabel      matlab.ui.control.Label
        EndEffector2DropDown           matlab.ui.control.DropDown
        NumberofRandomConfigurationsLabel  matlab.ui.control.Label
        NumberofRandomConfigurationsEditField  matlab.ui.control.NumericEditField
        VoxelsperDimensionLabel        matlab.ui.control.Label
        VoxelsperDimensionEditField    matlab.ui.control.NumericEditField
        GenerateWorkspaceButton        matlab.ui.control.Button
        ShowWorkspaceButton            matlab.ui.control.Button
        ShowVoxelizedWorkspaceButton   matlab.ui.control.Button
        VoxelizeWorkspaceButton        matlab.ui.control.Button
        ThirdLamp___________3          matlab.ui.control.Lamp
        Forth___________4              matlab.ui.control.Label
        PosesperVoxelLabel             matlab.ui.control.Label
        InitialJointPositionEditField  matlab.ui.control.EditField
        InitialJointPositionLabel      matlab.ui.control.Label
        NumberofIKIterationEditField   matlab.ui.control.EditField
        NumberofIKIterationLabel       matlab.ui.control.Label
        PosesperVoxelNumeric           matlab.ui.control.NumericEditField
        ThetaSlicesSliderLabel         matlab.ui.control.Label
        PhiSlicesSliderLabel           matlab.ui.control.Label
        PhiSlicesSlider                matlab.ui.control.Slider
        ThetaSlicesSlider              matlab.ui.control.Slider
        GenerateReachabilityMapButton  matlab.ui.control.Button
        ShowReachabilityMapButton      matlab.ui.control.Button
        LoadingGIF                     matlab.ui.control.Image
        StatusTextArea                 matlab.ui.control.TextArea
        MessageTextArea                matlab.ui.control.TextArea
        MessageTextAreaLabel           matlab.ui.control.Label
        StatusTextAreaLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
         % Description
        
    end
    
    properties (Access = public)
        robot_urdf % Robot urdf file stored here
        filename_urdf % Name of the user selected URDF file
        path_urdf % Path to the user selected URDF file

        path_customAIK
        filename_customAIK

        lower_limits
        upper_limits
        X_ee_array
        Y_ee_array
        Z_ee_array
        voxelsPerDim
        X_mesh
        Y_mesh
        Z_mesh
        R_mesh
        min_limits
        max_limits
        R_index
        both_halves
        % Modified-start
        endeffectorName1
        endeffectorName2
        NumOfEndeffector
        workspace
        workspace1
        workspace2
        baseName
        WorkSpaceSample
        ReachableSample
        RobotBasePosition
        HomeConfiguration
        DesiredConfigruation
        InitialConfiguration
        NumberofIKIteration
        % Modified-end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusTextArea.Value = "App is ready.";
            close all
            clear
            clc
        end

        % Button pushed function: BrowseURDFButton
        function BrowseURDFButtonPushed(app, event)
            try
                [app.filename_urdf, app.path_urdf] = uigetfile('*.urdf');
                app.URDFFileNameEditField.Value = app.filename_urdf;
                app.UIFigure.focus();
                app.robot_urdf = [];
                app.FirstLamp___________1.Color = [0.90, 0.90, 0.90];
            catch
                app.StatusTextArea.Value = "Error";
                app.FirstLamp___________1.Color = [1, 0, 0];
            end
        end

        % Button pushed function: ImportRobotButton
        function ImportRobotButtonPushed(app, event)
            % Chagne Status
            app.StatusTextArea.Value = "Start importing robot...";
            app.LoadingGIF.Visible = 'on';
            pause(0.0001);

            app.robot_urdf = importrobot([app.path_urdf, app.filename_urdf]);
            app.robot_urdf.DataFormat = 'column'; % Change data format

            if isa(app.robot_urdf, 'rigidBodyTree') == 1
                try
                    app.lower_limits = [];
                    app.upper_limits = [];
                    
                    % Obtaining joint limits from the robot's URDF
                    for i=1:length(app.robot_urdf.Bodies)
                        if app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(1)~=0 || app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(2)~=0

                            if app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(1)==-inf
                                app.lower_limits(end+1) = -pi; % joint lower limit in rad
                            else
                                app.lower_limits(end+1) = app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(1); % joint lower limit in rad
                            end

                            if app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(2)==inf
                                app.upper_limits(end+1) = pi; % joint upper limit in rad
                            else
                                app.upper_limits(end+1) = app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(2); % joint upper limit in rad
                            end

                            if strcmp(app.robot_urdf.Bodies{1,i}.Joint.Type, 'prismatic')
                                app.HomeConfiguration(length(app.lower_limits)) = (app.lower_limits(end)+app.upper_limits(end))/10;
                            end
                        end
                    end
                    app.lower_limits
                    app.upper_limits

                    % Default Base/End-Effector Setting
                    Items = app.robot_urdf.BodyNames;
                    Items{end} = 'NaN';
                    app.EndEffector1DropDown.Items = Items;            
                    app.EndEffector1DropDown.Value = Items(end);         
                    app.EndEffector2DropDown.Items = Items;            
                    app.EndEffector2DropDown.Value = Items(end);        

                    % Home/Desired Joint Position
                    app.HomeJointPositionEditField.Value = num2str(app.robot_urdf.homeConfiguration');
                    app.HomeConfiguration = app.robot_urdf.homeConfiguration;
                    % app.DesiredJointPositionEditField.Value = num2str(app.robot_urdf.homeConfiguration');
                    % app.DesiredConfigruation = app.robot_urdf.homeConfiguration;
                    app.DesiredJointPositionEditField.Value = "0  3.1  -2.8  0  0  0  0  0  0  3.1  -2.8  0  0  0  0  0";
                    app.DesiredConfigruation = str2num("0  3.1  -2.8  0  0  0  0  0  0  3.1  -2.8  0  0  0  0  0")';
                    

                    % Change Components States
                    app.FirstLamp___________1.Color=[0.00, 1.00, 0.00];
                    app.StatusTextArea.Value = "Robot URDF is imported successfully.";
                    app.LoadingGIF.Visible = 'off';

                    app.ShowRobotButton.Enable = "on";
                    app.HomeJointPositionEditField.Enable = "on";
                    app.HomeJointPositionLabel.Enable = "on";
                    app.DesiredJointPositionEditField.Enable = "on";
                    app.DesiredJointPositionLabel.Enable = "on";
                    app.EndEffector1DropDown.Enable = "on";    
                    app.EndEffector1DropDownLabel.Enable = "on";
                    app.NumberofRandomConfigurationsLabel.Enable = "on";
                    app.NumberofRandomConfigurationsEditField.Enable = "on";
                    app.VoxelsperDimensionLabel.Enable = "on";
                    app.VoxelsperDimensionEditField.Enable = "on";
                    app.GenerateWorkspaceButton.Enable = "off";

                catch
                    % Change Components States
                    app.FirstLamp___________1.Color=[1.00, 0.00, 0.00];
                    app.StatusTextArea.Value = "Problem reading the robot URDF.";
                    app.LoadingGIF.Visible = 'off';
                    
                    app.ShowRobotButton.Enable = "off";
                    app.HomeJointPositionEditField.Enable = "off";
                    app.HomeJointPositionLabel.Enable = "off";
                    app.DesiredJointPositionEditField.Enable = "off";
                    app.DesiredJointPositionLabel.Enable = "off";
                    app.EndEffector1DropDown.Enable = "off";  
                    app.EndEffector1DropDownLabel.Enable = "off";
                    app.NumberofRandomConfigurationsLabel.Enable = "off";
                    app.NumberofRandomConfigurationsEditField.Enable = "off";
                    app.VoxelsperDimensionLabel.Enable = "off";
                    app.VoxelsperDimensionEditField.Enable = "off";
                    app.GenerateWorkspaceButton.Enable = "off";
                end
            else
                % Change Components States
                app.FirstLamp___________1.Color=[1.00, 0.00, 0.00];
                app.StatusTextArea.Value = "Problem reading the robot URDF.";
                app.LoadingGIF.Visible = 'off';
                
                app.ShowRobotButton.Enable = "off";
                app.HomeJointPositionEditField.Enable = "off";
                app.HomeJointPositionLabel.Enable = "off";
                app.DesiredJointPositionEditField.Enable = "off";
                app.DesiredJointPositionLabel.Enable = "off";
                app.EndEffector1DropDown.Enable = "off";  
                app.EndEffector1DropDownLabel.Enable = "off";
                app.NumberofRandomConfigurationsLabel.Enable = "off";
                app.NumberofRandomConfigurationsEditField.Enable = "off";
                app.VoxelsperDimensionLabel.Enable = "off";
                app.VoxelsperDimensionEditField.Enable = "off";
                app.GenerateWorkspaceButton.Enable = "off";
            end
        end

        % Button pushed function: ShowRobotButton
        function ShowRobotButtonPushed(app, event)
            close
            %% Convex Hull 적용 및 Visualization
            figure("Name", 'Robot');
            title('Robot');
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            show(app.robot_urdf, app.DesiredConfigruation);
            grid on;
            axis equal
        end

        % Button pushed function: GenerateWorkspaceButton
        function GenerateWorkspaceButtonPushed(app, event)
            try
                % Chagne Status
                app.StatusTextArea.Value = "Start Workspace generation";
                app.LoadingGIF.Visible = 'on';
                app.SecondLamp___________2.Color = [0.90, 0.90, 0.90];
                pause(0.0001);
    
                % Excute Sampling
                [app.X_ee_array, app.Y_ee_array, app.Z_ee_array] = getWorkspaceRandom(app);
                app.workspace1 = convhull(app.X_ee_array(1, :), app.Y_ee_array(1, :), app.Z_ee_array(1, :));

                if app.NumOfEndeffector == 2
                    app.workspace2 = convhull(app.X_ee_array(2, :), app.Y_ee_array(2, :), app.Z_ee_array(2, :));
                end
    
                % Change Components States
                app.StatusTextArea.Value = "Robot workspace is generated.";
                app.LoadingGIF.Visible = 'off';
                app.SecondLamp___________2.Color = [0,1,0];

                app.ShowWorkspaceButton.Enable = "on";
                app.VoxelizeWorkspaceButton.Enable = "on";
            catch
                % Change Components States
                app.StatusTextArea.Value = "Something is wrong...";
                app.LoadingGIF.Visible = 'off';
                app.SecondLamp___________2.Color = [1,0,0];

                app.ShowWorkspaceButton.Enable = "on";
                app.VoxelizeWorkspaceButton.Enable = "on";
            end
        end

        % Button pushed function: ShowWorkspaceButton
        function ShowWorkspaceButtonPushed(app, event)
            close
            %% Convex Hull 적용 및 Visualization
            figure("Name", 'Workspace Sample');
            title('Robot Workspace Surface');
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
            show(app.robot_urdf, app.DesiredConfigruation);
            grid on;
            hold on

            trisurf(app.workspace1, app.X_ee_array(1, :), app.Y_ee_array(1, :), app.Z_ee_array(1, :), ...
                'Facecolor', 'blue', 'FaceAlpha', 0.2, 'EdgeColor', 'none');
            
            if app.NumOfEndeffector == 2
                trisurf(app.workspace2, app.X_ee_array(2, :), app.Y_ee_array(2, :), app.Z_ee_array(2, :), ...
                    'Facecolor', 'red', 'FaceAlpha', 0.2, 'EdgeColor', 'none');
            end

            axis equal
        end

        % Button pushed function: VoxelizeWorkspaceButton
        function VoxelizeWorkspaceButtonPushed(app, event)
            try
                % Chagne Status
                app.StatusTextArea.Value = "Workspace voxelization in progress...";
                app.LoadingGIF.Visible = 'on';
                app.SecondLamp___________2.Color = [0.90, 0.90, 0.90];
                pause(0.0001);
                
                
                % Excute Voxelization
                [app.X_mesh, app.Y_mesh, app.Z_mesh, app.R_mesh, app.min_limits, app.max_limits] = voxelizeWorkspace(app);
                
                % Change Components States
                app.StatusTextArea.Value = "Voxelized worksapce is generated.";
                app.LoadingGIF.Visible = 'off';
                app.SecondLamp___________2.Color=[0, 1, 0];

                app.ShowVoxelizedWorkspaceButton.Enable = "on";
                app.InitialJointPositionLabel.Enable = "on";
                app.InitialJointPositionEditField.Enable = "on";
                app.NumberofIKIterationLabel.Enable = "on";
                app.NumberofIKIterationEditField.Enable = "on";
                app.PosesperVoxelNumeric.Enable = 'on';
                app.PosesperVoxelLabel.Enable = 'on';
                app.PhiSlicesSlider.Enable = 'on';
                app.PhiSlicesSliderLabel.Enable = 'on';
                app.ThetaSlicesSlider.Enable = 'on';
                app.ThetaSlicesSliderLabel.Enable = 'on';
                app.GenerateReachabilityMapButton.Enable = 'on';

                % Initial Joint Position for IK
                app.InitialJointPositionEditField.Value = "0  0  0  0  0  0  0.01  0  0  0  0  0  0  0  0.01  0";
                app.InitialConfiguration = str2num("0  0  0  0  0  0  0.01  0  0  0  0  0  0  0  0.01  0")';

                % Number of IK Iteration
                app.NumberofIKIterationEditField.Value = num2str(500);
                app.NumberofIKIteration = 500;

            catch
                app.StatusTextArea.Value = "Something is wrong...";
                app.LoadingGIF.Visible = 'off';
                app.SecondLamp___________2.Color = [1,0,0];

                app.ShowVoxelizedWorkspaceButton.Enable = "off";
                app.InitialJointPositionLabel.Enable = "off";
                app.InitialJointPositionEditField.Enable = "off";
                app.NumberofIKIterationLabel.Enable = "off";
                app.NumberofIKIterationEditField.Enable = "off";
                app.PosesperVoxelNumeric.Enable = 'off';
                app.PosesperVoxelLabel.Enable = 'off';
                app.PhiSlicesSlider.Enable = 'off';
                app.PhiSlicesSliderLabel.Enable = 'off';
                app.ThetaSlicesSlider.Enable = 'off';
                app.ThetaSlicesSliderLabel.Enable = 'off';
                app.GenerateReachabilityMapButton.Enable = 'off';                
            end
        end

        % Button pushed function: ShowVoxelizedWorkspaceButton
        function ShowVoxelizedWorkspaceButtonPushed(app, event)
            close
            figure("Name", 'voxelworkspace');
            if app.NumOfEndeffector == 1
                scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), app.R_mesh(:)*50, 's', 'filled', 'MarkerFaceAlpha', 0.2);
            elseif app.NumOfEndeffector == 2
                R_mesh = app.R_mesh;
                R_mesh(isnan(R_mesh)) = 0;
                R_mesh = R_mesh(1,:) + R_mesh(2,:);
                R_mesh(R_mesh == 0) = NaN;
                scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), R_mesh(:)*50, 's', 'filled', 'MarkerFaceAlpha', 0.2);
            end
            title('Voxelized Workspace');
            axis equal
            hold on
            show(app.robot_urdf, app.DesiredConfigruation);
        end

        % Value changed function: ThetaSlicesSlider
        function ThetaSlicesSliderValueChanged(app, event)
            value = app.ThetaSlicesSlider.Value;
            app.ThetaSlicesSlider.Value = double(int32(value));
        end

        % Value changing function: ThetaSlicesSlider
        function ThetaSlicesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PosesperVoxelNumeric.Value = double(int32(changingValue)*int32(app.PhiSlicesSlider.Value));
        end

        % Value changed function: PhiSlicesSlider
        function PhiSlicesSliderValueChanged(app, event)
            value = app.PhiSlicesSlider.Value;
            app.PhiSlicesSlider.Value = double(int32(value));
        end

        % Value changing function: PhiSlicesSlider
        function PhiSlicesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PosesperVoxelNumeric.Value = double(int32(changingValue)*int32(app.ThetaSlicesSlider.Value));
        end

        % Button pushed function: GenerateReachabilityMapButton
        function GenerateReachabilityMapButtonPushed(app, event)
            try
                % Chagne Status
                app.StatusTextArea.Value = "Reachability map generation in progress ...";
                app.LoadingGIF.Visible = 'on';
                app.ThirdLamp___________3.Color = [0.90, 0.90, 0.90];
                app.MessageTextArea.Value = "";
                pause(0.001);

                % Compute Reachability
                app.R_index = getReachability(app);

                % Chagne Status
                app.StatusTextArea.Value = "Reachability map generation is completed.";
                app.LoadingGIF.Visible = 'off';
                app.ThirdLamp___________3.Color=[0, 1, 0];

                app.ShowReachabilityMapButton.Enable = "on";
            catch
                app.StatusTextArea.Value = "Something is wrong...";
                app.LoadingGIF.Visible = 'off';
                app.ThirdLamp___________3.Color=[1, 0, 0];
                
                app.ShowReachabilityMapButton.Enable = "off";
            end
        end

        % Button pushed function: ShowReachabilityMapButton
        function ShowReachabilityMapButtonPushed(app, event)
            SamplePerVoxel = app.PosesperVoxelNumeric.Value;
            close all
            figure("Name", 'reachability')
            show(app.robot_urdf, app.DesiredConfigruation);
            title('Reachability');
            hold on

            if app.NumOfEndeffector == 1
                scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), 50, app.R_index(:)/SamplePerVoxel, 'filled', 'o', 'MarkerFaceAlpha', 0.5);
            elseif app.NumOfEndeffector == 2
                scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), 50, app.R_index(1, :)/SamplePerVoxel, 'filled', 'o', 'MarkerFaceAlpha', 0.5);
                scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), 50, app.R_index(2, :)/SamplePerVoxel, 'filled', 'o', 'MarkerFaceAlpha', 0.5);
            end
            
            axis equal
            cb = colorbar;
            cb.Label.String = 'Reachability Index';
            
        end

        % Value changed function: EndEffector1DropDown
        function EndEffector1DropDownValueChanged(app, event)
            value = app.EndEffector1DropDown.Value;

            if strcmp(value, 'NaN')
                app.NumOfEndeffector = 0;
                app.EndEffector2DropDown.Enable = "off";
                app.EndEffector2DropDownLabel.Enable = "off";
                app.GenerateWorkspaceButton.Enable = "off";
            else
                app.NumOfEndeffector = 1;
                app.EndEffector2DropDown.Enable = "on";
                app.EndEffector2DropDownLabel.Enable = "on";
                app.GenerateWorkspaceButton.Enable = "on";

            end
        end

        % Value changed function: EndEffector2DropDown
        function EndEffector2DropDownValueChanged(app, event)
            value = app.EndEffector2DropDown.Value;

            if strcmp(value, 'NaN')
                app.NumOfEndeffector = 1;
            else
                app.NumOfEndeffector = 2;
            end

            app.VoxelizeWorkspaceButton.Enable = "off";
            app.ShowWorkspaceButton.Enable = "off";
            app.ShowVoxelizedWorkspaceButton.Enable = "off";
            app.InitialJointPositionLabel.Enable = "off";
            app.InitialJointPositionEditField.Enable = "off";
            app.NumberofIKIterationLabel.Enable = "off";
            app.NumberofIKIterationEditField.Enable = "off";
            app.PosesperVoxelNumeric.Enable = 'off';
            app.PosesperVoxelLabel.Enable = 'off';
            app.PhiSlicesSlider.Enable = 'off';
            app.PhiSlicesSliderLabel.Enable = 'off';
            app.ThetaSlicesSlider.Enable = 'off';
            app.ThetaSlicesSliderLabel.Enable = 'off';
            app.GenerateReachabilityMapButton.Enable = 'off';  
        end

        % Value changed function: DesiredJointPositionEditField
        function DesiredJointPositionEditFieldValueChanged(app, event)
            value = app.DesiredJointPositionEditField.Value;
            app.DesiredConfigruation = str2num(value)';
        end

        % Value changed function: InitialJointPositionEditField
        function InitialJointPositionEditFieldValueChanged(app, event)
            value = app.InitialJointPositionEditField.Value;
            app.InitialConfiguration = str2num(value)';
        end

        % Value changed function: NumberofIKIterationEditField
        function NumberofIKIterationEditFieldValueChanged(app, event)
            value = app.NumberofIKIterationEditField.Value;
            app.NumberofIKIteration = str2num(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.MessageTextArea.Editable = 'off';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.LoadingGIF = uiimage(app.UIFigure);
            app.LoadingGIF.Visible = 'off';
            app.LoadingGIF.ImageSource = fullfile(pathToMLAPP, 'loading.gif');
            app.ShowReachabilityMapButton = uibutton(app.UIFigure, 'push');
            app.ShowReachabilityMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowReachabilityMapButtonPushed, true);
            app.ShowReachabilityMapButton.Enable = 'off';
            app.ShowReachabilityMapButton.Text = 'Show Reachability Map';
            app.GenerateReachabilityMapButton = uibutton(app.UIFigure, 'push');
            app.GenerateReachabilityMapButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReachabilityMapButtonPushed, true);
            app.GenerateReachabilityMapButton.Enable = 'off';
            app.GenerateReachabilityMapButton.Text = 'Generate Reachability Map';
            app.ThetaSlicesSlider = uislider(app.UIFigure);
            app.ThetaSlicesSlider.Limits = [2 20];
            app.ThetaSlicesSlider.MajorTicks = [2 10 20 30 40 50 60 70 80 90 100];
            app.ThetaSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ThetaSlicesSliderValueChanged, true);
            app.ThetaSlicesSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaSlicesSliderValueChanging, true);
            app.ThetaSlicesSlider.Enable = 'off';
            app.ThetaSlicesSlider.Value = 2;
            app.PhiSlicesSlider = uislider(app.UIFigure);
            app.PhiSlicesSlider.Limits = [1 20];
            app.PhiSlicesSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.PhiSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @PhiSlicesSliderValueChanged, true);
            app.PhiSlicesSlider.ValueChangingFcn = createCallbackFcn(app, @PhiSlicesSliderValueChanging, true);
            app.PhiSlicesSlider.Enable = 'off';
            app.PhiSlicesSlider.Value = 1;
            app.PhiSlicesSliderLabel = uilabel(app.UIFigure);
            app.PhiSlicesSliderLabel.Enable = 'off';
            app.PhiSlicesSliderLabel.Text = 'Phi Slices:';
            app.ThetaSlicesSliderLabel = uilabel(app.UIFigure);
            app.ThetaSlicesSliderLabel.Enable = 'off';
            app.ThetaSlicesSliderLabel.Text = 'Theta Slices:';
            app.PosesperVoxelNumeric = uieditfield(app.UIFigure, 'numeric');
            app.PosesperVoxelNumeric.Limits = [2 400];
            app.PosesperVoxelNumeric.Editable = 'off';
            app.PosesperVoxelNumeric.Enable = 'off';
            app.PosesperVoxelNumeric.Value = 2;
            app.NumberofIKIterationLabel = uilabel(app.UIFigure);
            app.NumberofIKIterationLabel.Enable = 'off';
            app.NumberofIKIterationLabel.Text = 'Number of IK Iteration:';
            app.NumberofIKIterationEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofIKIterationEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIKIterationEditFieldValueChanged, true);
            app.NumberofIKIterationEditField.Enable = 'off';
            app.NumberofIKIterationEditField.Placeholder = 'Robot URDF File';
            app.NumberofIKIterationEditField.Value = 'Number of IK Iteration';
            app.InitialJointPositionLabel = uilabel(app.UIFigure);
            app.InitialJointPositionLabel.Enable = 'off';
            app.InitialJointPositionLabel.Text = 'Initial Joint Position:';
            app.InitialJointPositionEditField = uieditfield(app.UIFigure, 'text');
            app.InitialJointPositionEditField.ValueChangedFcn = createCallbackFcn(app, @InitialJointPositionEditFieldValueChanged, true);
            app.InitialJointPositionEditField.Enable = 'off';
            app.InitialJointPositionEditField.Placeholder = 'Robot URDF File';
            app.InitialJointPositionEditField.Value = 'Initial Position for Inverse Kinematics';
            app.PosesperVoxelLabel = uilabel(app.UIFigure);
            app.PosesperVoxelLabel.Enable = 'off';
            app.PosesperVoxelLabel.Text = 'Poses per Voxel';
            app.Forth___________4 = uilabel(app.UIFigure);
            app.Forth___________4.Text = '3. Generate Reachability Map';
            app.ThirdLamp___________3 = uilamp(app.UIFigure);
            app.VoxelizeWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.VoxelizeWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @VoxelizeWorkspaceButtonPushed, true);
            app.VoxelizeWorkspaceButton.Enable = 'off';
            app.VoxelizeWorkspaceButton.Text = 'Voxelize Workspace';
            app.ShowVoxelizedWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ShowVoxelizedWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVoxelizedWorkspaceButtonPushed, true);
            app.ShowVoxelizedWorkspaceButton.Enable = 'off';
            app.ShowVoxelizedWorkspaceButton.Text = 'Show Voxelized Workspace';
            app.ShowWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ShowWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowWorkspaceButtonPushed, true);
            app.ShowWorkspaceButton.Enable = 'off';
            app.ShowWorkspaceButton.Text = 'Show Workspace';
            app.GenerateWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.GenerateWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWorkspaceButtonPushed, true);
            app.GenerateWorkspaceButton.Enable = 'off';
            app.GenerateWorkspaceButton.Text = 'Generate Workspace';
            app.VoxelsperDimensionEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelsperDimensionEditField.Limits = [5 100];
            app.VoxelsperDimensionEditField.ValueDisplayFormat = '%.0f';
            app.VoxelsperDimensionEditField.Enable = 'off';
            app.VoxelsperDimensionEditField.Value = 10;
            app.VoxelsperDimensionLabel = uilabel(app.UIFigure);
            app.VoxelsperDimensionLabel.Enable = 'off';
            app.VoxelsperDimensionLabel.Text = 'Voxels per Dimension:';
            app.NumberofRandomConfigurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofRandomConfigurationsEditField.Limits = [500 100000];
            app.NumberofRandomConfigurationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofRandomConfigurationsEditField.Enable = 'off';
            app.NumberofRandomConfigurationsEditField.Value = 10000;
            app.NumberofRandomConfigurationsLabel = uilabel(app.UIFigure);
            app.NumberofRandomConfigurationsLabel.Enable = 'off';
            app.NumberofRandomConfigurationsLabel.Text = 'Number of Random Configurations:';
            app.EndEffector2DropDown = uidropdown(app.UIFigure);
            app.EndEffector2DropDown.Items = {'end effector'};
            app.EndEffector2DropDown.ValueChangedFcn = createCallbackFcn(app, @EndEffector2DropDownValueChanged, true);
            app.EndEffector2DropDown.Enable = 'off';
            app.EndEffector2DropDown.Value = 'end effector';
            app.EndEffector2DropDownLabel = uilabel(app.UIFigure);
            app.EndEffector2DropDownLabel.Enable = 'off';
            app.EndEffector2DropDownLabel.Text = 'End-Effector2';
            app.EndEffector1DropDown = uidropdown(app.UIFigure);
            app.EndEffector1DropDown.Items = {'end effector'};
            app.EndEffector1DropDown.ValueChangedFcn = createCallbackFcn(app, @EndEffector1DropDownValueChanged, true);
            app.EndEffector1DropDown.Enable = 'off';
            app.EndEffector1DropDown.Value = 'end effector';
            app.EndEffector1DropDownLabel = uilabel(app.UIFigure);
            app.EndEffector1DropDownLabel.Enable = 'off';
            app.EndEffector1DropDownLabel.Text = 'End-Effector1';
            app.Second___________2 = uilabel(app.UIFigure);
            app.Second___________2.Text = '2. Workspace Discretization & Voxelization';
            app.SecondLamp___________2 = uilamp(app.UIFigure);
            app.ShowRobotButton = uibutton(app.UIFigure, 'push');
            app.ShowRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRobotButtonPushed, true);
            app.ShowRobotButton.Enable = 'off';
            app.ShowRobotButton.Text = 'Show Robot';
            app.ImportRobotButton = uibutton(app.UIFigure, 'push');
            app.ImportRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRobotButtonPushed, true);
            app.ImportRobotButton.Text = 'Import Robot';
            app.BrowseURDFButton = uibutton(app.UIFigure, 'push');
            app.BrowseURDFButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseURDFButtonPushed, true);
            app.BrowseURDFButton.Text = 'Browse URDF';
            app.DesiredJointPositionLabel = uilabel(app.UIFigure);
            app.DesiredJointPositionLabel.Enable = 'off';
            app.DesiredJointPositionLabel.Text = 'Desired Joint Position:';
            app.DesiredJointPositionEditField = uieditfield(app.UIFigure, 'text');
            app.DesiredJointPositionEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredJointPositionEditFieldValueChanged, true);
            app.DesiredJointPositionEditField.Enable = 'off';
            app.DesiredJointPositionEditField.Placeholder = 'Robot URDF File';
            app.DesiredJointPositionEditField.Value = 'Desired Joint Position';
            app.HomeJointPositionLabel = uilabel(app.UIFigure);
            app.HomeJointPositionLabel.Enable = 'off';
            app.HomeJointPositionLabel.Text = 'Home Joint Position:';
            app.HomeJointPositionEditField = uieditfield(app.UIFigure, 'text');
            app.HomeJointPositionEditField.Editable = 'off';
            app.HomeJointPositionEditField.Enable = 'off';
            app.HomeJointPositionEditField.Placeholder = 'Robot URDF File';
            app.HomeJointPositionEditField.Value = 'Home Joint Position';
            app.URDFFileNameLabel = uilabel(app.UIFigure);
            app.URDFFileNameLabel.Text = 'URDF File Name:';
            app.URDFFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.URDFFileNameEditField.Placeholder = 'Robot URDF File';
            app.First___________1 = uilabel(app.UIFigure);
            app.First___________1.Text = '1. Import URDF';
            app.FirstLamp___________1 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReachabilityGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end